classdef sree_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Covid19DataVisualizationBySreeDataSourceJHUUIFigure  matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionLabel         matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        Country
        States
        Dates
        Data
        Cases
        Deaths
        TotalCases
        TotalDeath
        ShowCumulative
        ShowDaily
        ShowCases
        ShowDeath
        StateSelected
        AvgWindow
    end
    
    methods (Access = private)
        
        function plotData(app)
            app.UIAxes.reset;
            if ismember(app.CountryListBox.Value,{'Global'})
                app.Cases = app.Data(:,1:2:end);
                app.Deaths = app.Data(:,2:2:end);
            else
                if ismember(app.StateorRegionListBox.Value,{'All'})
                    app.Cases = app.Data(ismember(app.Country,app.CountryListBox.Value),1:2:end);
                    app.Deaths = app.Data(ismember(app.Country,app.CountryListBox.Value),2:2:end);
                else
                    app.Cases = app.Data(ismember(app.States,app.StateorRegionListBox.Value),1:2:end);
                    app.Deaths = app.Data(ismember(app.States,app.StateorRegionListBox.Value),2:2:end);
                end
            end
            if size(app.Cases,1)>1
                app.TotalCases = sum(app.Cases,1);
                app.TotalDeath = sum(app.Deaths,1);
            else
                app.TotalCases = app.Cases;
                app.TotalDeath = app.Deaths;
            end
            if app.ShowDaily
                app.TotalCases = [0 diff(app.TotalCases)];
                app.TotalDeath = [0 diff(app.TotalDeath)];
            end
            app.TotalCases = movmean(app.TotalCases,app.AvgWindow);
            app.TotalDeath = movmean(app.TotalDeath,app.AvgWindow);
            if app.ShowCases>0
                if app.BothButton.Value
                    yyaxis(app.UIAxes,'left');
                end
                bar(app.UIAxes,datenum(app.Dates),app.TotalCases,'blue');
                app.UIAxes.YTickLabel = num2str(get(app.UIAxes,'YTick')');
                hold(app.UIAxes,'on');
            end
            if app.ShowDeath >0
                if app.BothButton.Value
                    yyaxis(app.UIAxes,'right');
                end
                plot(app.UIAxes,datenum(app.Dates),app.TotalDeath,'r','LineWidth',2);
                app.UIAxes.YTickLabel = num2str(get(app.UIAxes,'YTick')');
            end
            datetick(app.UIAxes,'x',19,'keeplimits');
            if ismember(app.CountryListBox.Value,{'Global'})
                if app.CumulativeButton.Value
                    if app.CasesButton.Value
                        title(app.UIAxes,'Cumulative Number of Cases Globally');
                    elseif app.DeathsButton.Value
                        title(app.UIAxes,'Cumulative Number of Deaths Globally');
                    else
                        title(app.UIAxes,'Cumulative Number of Cases and Deaths Globally');
                    end
                else
                    if app.CasesButton.Value
                        title(app.UIAxes,'Daily Number of Cases Globally');
                    elseif app.DeathsButton.Value
                        title(app.UIAxes,'Daily Number of Deaths Globally');
                    else
                        title(app.UIAxes,'Daily Number of Cases and Deaths Globally');
                    end
                end
            else
                if ismember(app.StateorRegionListBox.Value,{'All'})
                    printname = char(app.CountryListBox.Value);
                else
                    printname = char(app.StateorRegionListBox.Value);
                end
                if app.CumulativeButton.Value
                    if app.CasesButton.Value
                        title(app.UIAxes,['Cumulative Number of Cases in ' printname]);
                    elseif app.DeathsButton.Value
                        title(app.UIAxes,['Cumulative Number of Deaths in ' printname]);
                    else
                        title(app.UIAxes,['Cumulative Number of Cases and Deaths in ' printname]);
                    end
                else
                    if app.CasesButton.Value
                        title(app.UIAxes,['Daily Number of Cases in ' printname]);
                    elseif app.DeathsButton.Value
                        title(app.UIAxes,['Daily Number of Deaths in ' printname]);
                    else
                        title(app.UIAxes,['Daily Number of Cases and Deaths in ' printname]);
                    end
                end
            end
            if app.AvgWindow > 1
                title(app.UIAxes,[app.UIAxes.Title.String ' (' num2str(round(app.AvgWindow)) '-day mean)'])
            end
            hold(app.UIAxes,'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data
            app.Country = covid_data(2:end,1);
            app.States = covid_data(2:end,2);
            app.Dates = datevec(covid_data(1,3:end),'mm/dd/yy');
            app.Data = cell2mat(covid_data(2:end,3:end));
            app.CountryListBox.Items = [{'Global'};unique(app.Country)];
            app.StateorRegionListBox.Items = {'All'};
            app.ShowCumulative = 1;
            app.ShowCases = 1;
            app.AvgWindow = 1;
            app.plotData();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            
            if ~ismember(app.CountryListBox.Value,{'Global'})
                app.StateorRegionListBox.Items=[{'All'};unique(app.States(ismember(app.Country,app.CountryListBox.Value)))];
                if length(app.StateorRegionListBox.Items)>1
                    app.StateorRegionListBox.Items(2)=[];
                else
                    app.StateorRegionListBox.Items(1)=[];
                end
            end
            app.plotData();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.plotData();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.ShowCumulative = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            app.plotData();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeath = app.DeathsButton.Value || app.BothButton.Value;
            app.plotData();
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            app.AvgWindow = round(event.Value);
            app.AveragedofdaysSlider.Value = app.AvgWindow;
            app.plotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure = uifigure('Visible', 'off');
            app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure.Name = 'Covid-19 Data Visualization By Sree (Data Source: JHU)';
            app.UIAxes = uiaxes(app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            zlabel(app.UIAxes, 'Z')
            app.CountryListBoxLabel = uilabel(app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure);
            app.CountryListBox.Items = {'Global'};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = 'Global';
            app.StateorRegionLabel = uilabel(app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure);
            app.StateorRegionLabel.Text = {'State or '; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure);
            app.StateorRegionListBox.Items = {'All'};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = 'All';
            app.AveragedofdaysSliderLabel = uilabel(app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AveragedofdaysSlider = uislider(app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sree_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Covid19DataVisualizationBySreeDataSourceJHUUIFigure)
        end
    end
end